classdef c2000_epwm_conf_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        GridLayout_2                    matlab.ui.container.GridLayout
        CMPxvalueEditField              matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        Dutycycle0100Spinner            matlab.ui.control.Spinner
        Dutycycle0100Label              matlab.ui.control.Label
        C2000EPWMLabel                  matlab.ui.control.Label
        TimeBaseApplyButton             matlab.ui.control.Button
        TCTRmsSpinner                   matlab.ui.control.Spinner
        TCTRmsSpinnerLabel              matlab.ui.control.Label
        TimerperiodTBPRDEditField       matlab.ui.control.NumericEditField
        TimerperiodTBPRDEditFieldLabel  matlab.ui.control.Label
        TBCLKMhzEditField               matlab.ui.control.NumericEditField
        TBCLKMhzEditFieldLabel          matlab.ui.control.Label
        CLKDIVTBCLKDIVDropDown          matlab.ui.control.DropDown
        CLKDIVTBCLKDIVDropDownLabel     matlab.ui.control.Label
        CountingmodeDropDown            matlab.ui.control.DropDown
        CountingmodeDropDownLabel       matlab.ui.control.Label
        EPWMCLKMhzSpinner               matlab.ui.control.Spinner
        EPWMCLKMhzSpinnerLabel          matlab.ui.control.Label
        HSPCLKDIVDropDown               matlab.ui.control.DropDown
        HSPCLKDIVDropDownLabel          matlab.ui.control.Label
    end

    methods (Access = private)

        function hz = mhz2hz(~, mhz)
            khz = mhz * 1000;
            hz  = khz * 1000;
        end

        function s = ms2s(~, ms)
            s = ms / 1000;
        end

        function s = mhz2s(app, mhz)
            s = 1 / mhz2hz(app, mhz);
        end

        % Check parameters are not zero
        function isFaild = vaildIn(~, values)
            isFaild = false;

            for v = values
                if v == 0
                    isFaild = true;
                end
            end
        end

        % Reset component values
        function resetApp(app)
            app.TBCLKMhzEditField.Value = 0;
            app.TimerperiodTBPRDEditField.Value = 0;
            app.TCTRmsSpinner.Value = 0;
            app.EPWMCLKMhzSpinner.Value = 0;
            app.Dutycycle0100Spinner.Value = 0;
            app.CMPxvalueEditField.Value = 0;
            app.CountingmodeDropDown.Value = "Up";
            app.CLKDIVTBCLKDIVDropDown.Value = "1";
            app.HSPCLKDIVDropDown.Value = "1";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function EPWMCLKMhzSpinnerValueChanged(app, event)

        end

        % Callback function
        function ResetButtonPushed(app, event)
            resetApp(app);
        end

        % Button pushed function: TimeBaseApplyButton
        function TimeBaseApplyButtonPushed(app, event)
            % Get component value
            tbprd = 0;
            epwmFreq = app.EPWMCLKMhzSpinner.Value; % EPWMCLK
            clkdiv = str2double(app.CLKDIVTBCLKDIVDropDown.Value); % CLKDIV
            hspclkdiv = str2double(app.HSPCLKDIVDropDown.Value); % HSPCLKDIV 
            cntMode = app.CountingmodeDropDown.Value; % Counting Mode
            tctr_s = ms2s(app, app.TCTRmsSpinner.Value); % TCTR
            tbclk = epwmFreq / (hspclkdiv * clkdiv); % TBCLK
            tbclk_s = mhz2s(app, tbclk);
            % Duty cycle
            cmpValue = 0;
            dutycycle = app.Dutycycle0100Spinner.Value / 100; % Duty Cycle

            if vaildIn(app, [epwmFreq tctr_s])
                resetApp(app);
                return
            end

            switch cntMode
                case {'Up' 'Down'}
                    tbprd = (tctr_s / tbclk_s) - 1;
                    cmpValue = (1 - dutycycle) * tbprd;
                case 'Up-Down'
                    tbprd = tctr_s / (2 * tbclk_s);
                    cmpValue = (1 - dutycycle) * tbprd;
            end

            % Show result
            app.TBCLKMhzEditField.Value = tbclk;
            app.TimerperiodTBPRDEditField.Value = tbprd;
            app.CMPxvalueEditField.Value = cmpValue;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_2 = uigridlayout(app.GridLayout);
            app.HSPCLKDIVDropDownLabel = uilabel(app.GridLayout_2);
            app.HSPCLKDIVDropDownLabel.Text = 'HSPCLKDIV ';
            app.HSPCLKDIVDropDown = uidropdown(app.GridLayout_2);
            app.HSPCLKDIVDropDown.Items = {'1', '2', '4', '6', '8', '10', '12', '14'};
            app.HSPCLKDIVDropDown.Value = '1';
            app.EPWMCLKMhzSpinnerLabel = uilabel(app.GridLayout_2);
            app.EPWMCLKMhzSpinnerLabel.Text = 'EPWMCLK (Mhz)';
            app.EPWMCLKMhzSpinner = uispinner(app.GridLayout_2);
            app.EPWMCLKMhzSpinner.Limits = [0 Inf];
            app.EPWMCLKMhzSpinner.RoundFractionalValues = 'on';
            app.EPWMCLKMhzSpinner.ValueDisplayFormat = '%u';
            app.CountingmodeDropDownLabel = uilabel(app.GridLayout_2);
            app.CountingmodeDropDownLabel.Text = 'Counting mode';
            app.CountingmodeDropDown = uidropdown(app.GridLayout_2);
            app.CountingmodeDropDown.Items = {'Up', 'Down', 'Up-Down'};
            app.CountingmodeDropDown.Value = 'Up';
            app.CLKDIVTBCLKDIVDropDownLabel = uilabel(app.GridLayout_2);
            app.CLKDIVTBCLKDIVDropDownLabel.Text = 'CLKDIV (TBCLKDIV)';
            app.CLKDIVTBCLKDIVDropDown = uidropdown(app.GridLayout_2);
            app.CLKDIVTBCLKDIVDropDown.Items = {'1', '2', '4', '8', '16', '32', '64', '128'};
            app.CLKDIVTBCLKDIVDropDown.Value = '1';
            app.TBCLKMhzEditFieldLabel = uilabel(app.GridLayout_2);
            app.TBCLKMhzEditFieldLabel.Text = 'TBCLK (Mhz)';
            app.TBCLKMhzEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.TBCLKMhzEditField.Limits = [0 Inf];
            app.TBCLKMhzEditField.ValueDisplayFormat = '%.8f';
            app.TBCLKMhzEditField.Editable = 'off';
            app.TimerperiodTBPRDEditFieldLabel = uilabel(app.GridLayout_2);
            app.TimerperiodTBPRDEditFieldLabel.Text = 'Timer period (TBPRD)';
            app.TimerperiodTBPRDEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.TimerperiodTBPRDEditField.Limits = [0 Inf];
            app.TimerperiodTBPRDEditField.ValueDisplayFormat = '%.0f';
            app.TimerperiodTBPRDEditField.Editable = 'off';
            app.TCTRmsSpinnerLabel = uilabel(app.GridLayout_2);
            app.TCTRmsSpinnerLabel.Text = 'TCTR (ms)';
            app.TCTRmsSpinner = uispinner(app.GridLayout_2);
            app.TCTRmsSpinner.Limits = [0 Inf];
            app.TCTRmsSpinner.RoundFractionalValues = 'on';
            app.TCTRmsSpinner.ValueDisplayFormat = '%u';
            app.TimeBaseApplyButton = uibutton(app.GridLayout_2, 'push');
            app.TimeBaseApplyButton.ButtonPushedFcn = createCallbackFcn(app, @TimeBaseApplyButtonPushed, true);
            app.TimeBaseApplyButton.Text = 'Apply';
            app.C2000EPWMLabel = uilabel(app.GridLayout_2);
            app.C2000EPWMLabel.Text = 'C2000 ePWM Configure Calculator';
            app.Dutycycle0100Label = uilabel(app.GridLayout_2);
            app.Dutycycle0100Label.Text = 'Duty cycle (0~100)';
            app.Dutycycle0100Spinner = uispinner(app.GridLayout_2);
            app.Dutycycle0100Spinner.Limits = [0 100];
            app.Dutycycle0100Spinner.RoundFractionalValues = 'on';
            app.Dutycycle0100Spinner.ValueDisplayFormat = '%u';
            app.EditFieldLabel = uilabel(app.GridLayout_2);
            app.EditFieldLabel.Text = 'CMPx value';
            app.CMPxvalueEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.CMPxvalueEditField.Limits = [0 Inf];
            app.CMPxvalueEditField.ValueDisplayFormat = '%.0f';
            app.CMPxvalueEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = c2000_epwm_conf_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end