classdef c2000_epwm_tb_calc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        C2000EPWMLabel                  matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        TCTRmsSpinner                   matlab.ui.control.Spinner
        TCTRmsSpinnerLabel              matlab.ui.control.Label
        TimerperiodTBPRDEditField       matlab.ui.control.NumericEditField
        TimerperiodTBPRDEditFieldLabel  matlab.ui.control.Label
        TBCLKMhzEditField               matlab.ui.control.NumericEditField
        TBCLKMhzEditFieldLabel          matlab.ui.control.Label
        CLKDIVTBCLKDIVDropDown          matlab.ui.control.DropDown
        CLKDIVTBCLKDIVDropDownLabel     matlab.ui.control.Label
        CountingmodeDropDown            matlab.ui.control.DropDown
        CountingmodeDropDownLabel       matlab.ui.control.Label
        EPWMCLKMhzSpinner               matlab.ui.control.Spinner
        EPWMCLKMhzSpinnerLabel          matlab.ui.control.Label
        HSPCLKDIVDropDown               matlab.ui.control.DropDown
        HSPCLKDIVDropDownLabel          matlab.ui.control.Label
    end

    methods (Access = private)

        function hz = mhz2hz(~, mhz)
            khz = mhz * 1000;
            hz  = khz * 1000;
        end

        function s = ms2s(~, ms)
            s = ms / 1000;
        end

        function s = mhz2s(app, mhz)
            s = 1 / mhz2hz(app, mhz);
        end

        % Check parameters are not zero
        function isFaild = vaildIn(~, values)
            isFaild = false;

            for v = values
                if v == 0
                    isFaild = true;
                end
            end
        end

        % Reset component values
        function resetApp(app)
            app.TBCLKMhzEditField.Value = 0;
            app.TimerperiodTBPRDEditField.Value = 0;
            app.TCTRmsSpinner.Value = 0;
            app.EPWMCLKMhzSpinner.Value = 0;
            app.CountingmodeDropDown.Value = "Up";
            app.CLKDIVTBCLKDIVDropDown.Value = "1";
            app.HSPCLKDIVDropDown.Value = "1";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function EPWMCLKMhzSpinnerValueChanged(app, event)

        end

        % Callback function
        function ResetButtonPushed(app, event)
            resetApp(app);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Get component value
            tbprd = 0;
            epwmFreq = app.EPWMCLKMhzSpinner.Value; % EPWMCLK
            clkdiv = str2double(app.CLKDIVTBCLKDIVDropDown.Value); % CLKDIV
            hspclkdiv = str2double(app.HSPCLKDIVDropDown.Value); % HSPCLKDIV 
            cntMode = app.CountingmodeDropDown.Value; % Counting Mode
            tctr_s = ms2s(app, app.TCTRmsSpinner.Value); % TCTR
            tbclk = epwmFreq / (hspclkdiv * clkdiv); % TBCLK
            tbclk_s = mhz2s(app, tbclk);

            if vaildIn(app, [epwmFreq tctr_s])
                resetApp(app);
                return
            end

            switch cntMode
                case {'Up' 'Down'}
                    tbprd = (tctr_s / tbclk_s) - 1;
                case 'Up-Down'
                    tbprd = tctr_s / (2 * tbclk_s);
            end

            % Show result
            app.TBCLKMhzEditField.Value = tbclk;
            app.TimerperiodTBPRDEditField.Value = tbprd;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HSPCLKDIVDropDownLabel = uilabel(app.GridLayout);
            app.HSPCLKDIVDropDownLabel.Text = 'HSPCLKDIV ';
            app.HSPCLKDIVDropDown = uidropdown(app.GridLayout);
            app.HSPCLKDIVDropDown.Items = {'1', '2', '4', '6', '8', '10', '12', '14'};
            app.HSPCLKDIVDropDown.Value = '1';
            app.EPWMCLKMhzSpinnerLabel = uilabel(app.GridLayout);
            app.EPWMCLKMhzSpinnerLabel.Text = 'EPWMCLK (Mhz)';
            app.EPWMCLKMhzSpinner = uispinner(app.GridLayout);
            app.EPWMCLKMhzSpinner.Limits = [0 Inf];
            app.EPWMCLKMhzSpinner.RoundFractionalValues = 'on';
            app.EPWMCLKMhzSpinner.ValueDisplayFormat = '%u';
            app.CountingmodeDropDownLabel = uilabel(app.GridLayout);
            app.CountingmodeDropDownLabel.Text = 'Counting mode';
            app.CountingmodeDropDown = uidropdown(app.GridLayout);
            app.CountingmodeDropDown.Items = {'Up', 'Down', 'Up-Down'};
            app.CountingmodeDropDown.Value = 'Up';
            app.CLKDIVTBCLKDIVDropDownLabel = uilabel(app.GridLayout);
            app.CLKDIVTBCLKDIVDropDownLabel.Text = 'CLKDIV (TBCLKDIV)';
            app.CLKDIVTBCLKDIVDropDown = uidropdown(app.GridLayout);
            app.CLKDIVTBCLKDIVDropDown.Items = {'1', '2', '4', '8', '16', '32', '64', '128'};
            app.CLKDIVTBCLKDIVDropDown.Value = '1';
            app.TBCLKMhzEditFieldLabel = uilabel(app.GridLayout);
            app.TBCLKMhzEditFieldLabel.Text = 'TBCLK (Mhz)';
            app.TBCLKMhzEditField = uieditfield(app.GridLayout, 'numeric');
            app.TBCLKMhzEditField.Limits = [0 Inf];
            app.TBCLKMhzEditField.ValueDisplayFormat = '%.8f';
            app.TBCLKMhzEditField.Editable = 'off';
            app.TimerperiodTBPRDEditFieldLabel = uilabel(app.GridLayout);
            app.TimerperiodTBPRDEditFieldLabel.Text = 'Timer period (TBPRD)';
            app.TimerperiodTBPRDEditField = uieditfield(app.GridLayout, 'numeric');
            app.TimerperiodTBPRDEditField.Limits = [0 Inf];
            app.TimerperiodTBPRDEditField.ValueDisplayFormat = '%.0f';
            app.TimerperiodTBPRDEditField.Editable = 'off';
            app.TCTRmsSpinnerLabel = uilabel(app.GridLayout);
            app.TCTRmsSpinnerLabel.Text = 'TCTR (ms)';
            app.TCTRmsSpinner = uispinner(app.GridLayout);
            app.TCTRmsSpinner.Limits = [0 Inf];
            app.TCTRmsSpinner.RoundFractionalValues = 'on';
            app.TCTRmsSpinner.ValueDisplayFormat = '%u';
            app.ApplyButton = uibutton(app.GridLayout, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.C2000EPWMLabel = uilabel(app.GridLayout);
            app.C2000EPWMLabel.Text = 'C2000 ePWM Time Base Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = c2000_epwm_tb_calc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end